classdef hidrostatik_transmisyon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        HzDeiimleriTab                  matlab.ui.container.Tab
        AsalvmeDeiimims2CheckBox        matlab.ui.control.CheckBox
        BasnFarkDeiimHzpsCheckBox       matlab.ui.control.CheckBox
        MotorAsDeiimHzradsCheckBox      matlab.ui.control.CheckBox
        PompaAsDeiimHzradsCheckBox      matlab.ui.control.CheckBox
        ADeiimiTab                      matlab.ui.container.Tab
        MotorAsalHzGiriimsCheckBox      matlab.ui.control.CheckBox
        BasnFarkpCheckBox               matlab.ui.control.CheckBox
        MotorAsradCheckBox              matlab.ui.control.CheckBox
        PompaAsradCheckBox              matlab.ui.control.CheckBox
        SistemGirileriPanel             matlab.ui.container.Panel
        x2radSlider                     matlab.ui.control.Slider
        x2radSliderLabel                matlab.ui.control.Label
        x1radSlider                     matlab.ui.control.Slider
        x1radSliderLabel                matlab.ui.control.Label
        x4msEditField                   matlab.ui.control.NumericEditField
        x4msEditFieldLabel              matlab.ui.control.Label
        x3pEditField                    matlab.ui.control.NumericEditField
        x3pEditFieldLabel               matlab.ui.control.Label
        SimulationPanel                 matlab.ui.container.Panel
        VeriYokLamp                     matlab.ui.control.Lamp
        VeriYokLampLabel                matlab.ui.control.Label
        StopTimeSlider                  matlab.ui.control.Slider
        StopTimeSliderLabel             matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        StopTimeEditField               matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel          matlab.ui.control.Label
        KontrolGirileriECUPanel         matlab.ui.container.Panel
        MotorKontrolGiriipedalu2Slider  matlab.ui.control.Slider
        MotorKontrolGiriipedalu2SliderLabel  matlab.ui.control.Label
        PompaKontrolGiriivitesu1Slider  matlab.ui.control.Slider
        PompaKontrolGiriivitesu1SliderLabel  matlab.ui.control.Label
        ParametrelerPanel               matlab.ui.container.Panel
        T_loadNmEditField               matlab.ui.control.EditField
        T_loadNmEditFieldLabel          matlab.ui.control.Label
        GiriPompaAsalHzradsnEditField   matlab.ui.control.NumericEditField
        GiriPompaAsalHzradsnEditFieldLabel  matlab.ui.control.Label
        SaveParametersButton            matlab.ui.control.Button
        HidrolikTransmisyonHesaplamaLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        grafik                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hydropump_speed_diffLine;
        hydromotor_speed_diffLine;
        pressure_difference_changeLine;
        hydromotor_acc_diffLine;
          hydropump_angleLine;
        hydromotor_angleLine;
        pressure_differenceLine;
        hydromotor_accLine;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

    mdl="hidrostatik_simulink1";
    load_system(mdl);
    out=sim(mdl);
    t=out.tout;
      veri1 =out.degisim_aci_p;
       
        % Kontrol: Eğer veri varsa lambayı yeşil yap
        if ~isempty(veri1)
            app.VeriYokLamp.Color = 'green';
            app.VeriYokLampLabel.Text = 'Veriler Alındı';
        else
            % Veri boşsa hata mesajı
            uialert(app.UIFigure, 'Veriler alınamadı.', 'Uyarı');
        end
    %açı değişimleri out.a(n) ile aktarıldı.
    a1=out.degisim_aci_p;
   a2=out.degisim_aci_m;
   a3=out.basinc_degisimi;
   a4=out.motor_acisal_hiz_girisi;
   %grafiklere çizdirirken plot komutu kullanımı sebebiyle matris boyutunu
   %squeeze komutuyla sıkıştırıyoruz.
    app.hydropump_angleLine=plot(app.grafik,t,squeeze(a1));
    hold(app.grafik,"on");
    app.hydromotor_angleLine=plot(app.grafik,t,squeeze(a2));
    hold(app.grafik,"on");  
    app.pressure_differenceLine=plot(app.grafik,t,squeeze(a3));
    hold(app.grafik,"on");
    app.hydromotor_accLine=plot(app.grafik,t,squeeze(a4));
    hold(app.grafik,"on");
    %verileri check box ile kontrol etmek için başlangıçta görünümlerini
    %gizlendi.
    app.hydropump_angleLine.Visible="off";
    app.hydromotor_angleLine.Visible="off";
    app.pressure_differenceLine.Visible="off";
    app.hydromotor_accLine.Visible="off";

    %Hız değişimleri out.q(n) ile aktarıldı.
    q1=out.hydropump_speed_diff;
    q2=out.hydromotor_speed_diff;
    q3=out.debi;
    q4=out.hydromotor_acc_diff;
     %verileri check box ile kontrol etmek için başlangıçta görünümlerini
    %gizlendi.
    app.hydropump_speed_diffLine=plot(app.grafik,t,squeeze(q1));
    hold(app.grafik,"on");
    app.hydromotor_speed_diffLine=plot(app.grafik,t,squeeze(q2));
    hold(app.grafik,"on");  
    app.pressure_difference_changeLine=plot(app.grafik,t,squeeze(q3));
    hold(app.grafik,"on");
    app.hydromotor_acc_diffLine=plot(app.grafik,t,squeeze(q4));
    hold(app.grafik,"on");
     
%grafiklere çizdirirken plot komutu kullanımı sebebiyle matris boyutunu
   %squeeze komutuyla sıkıştırıyoruz
pompa_hiz_degisim     = squeeze(out.hydropump_speed_diff);
motor_hiz_degisim     = squeeze(out.hydromotor_speed_diff);
debi_degisim          = squeeze(out.debi);
motor_ivme_degisim    = squeeze(out.hydromotor_acc_diff);
zaman                 = squeeze(out.tout);  % Simülasyon zamanı

N = min([length(zaman), length(pompa_hiz_degisim), length(motor_hiz_degisim), ...
         length(debi_degisim), length(motor_ivme_degisim)]);

zaman               = zaman(1:N);
pompa_hiz_degisim   = pompa_hiz_degisim(1:N);
motor_hiz_degisim   = motor_hiz_degisim(1:N);
debi_degisim        = debi_degisim(1:N);
motor_ivme_degisim  = motor_ivme_degisim(1:N);

% Tabloya bu hız değişimlerini aktarabilmek için zamana bağlı ve uzunluklarını eşitleyen(uzunluklar farklı olduğunda hata alıyoruz) kodlar yazıldı. 
tablo_verisi = table(zaman, pompa_hiz_degisim, motor_hiz_degisim, ...
                     debi_degisim, motor_ivme_degisim, ...
                     'VariableNames', {'Zaman', 'PompaHizDegisimi', 'MotorHizDegisimi', 'DebiDegisimi', 'MotorIvmelenmeDegisimi'});
    app.UITable.Data=tablo_verisi;

    app.hydropump_speed_diffLine.Visible="off";
    app.hydromotor_speed_diffLine.Visible="off";
    app.pressure_difference_changeLine.Visible="off";
    app.hydromotor_acc_diffLine.Visible="off";

    xlabel(app.grafik,"time(sec)");
    ylabel(app.grafik,"Değerler");
    legend(app.grafik,["hydropump_speed_diff", "hydromotor_speed_diff","pressure_difference_change", "hydromotor_acceleration_diff"]);

        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
          
          cla(app.grafik);
            w_pump = app.GiriPompaAsalHzradsnEditField.Value;
            funcstr= app.T_loadNmEditField.Value;
            StopTime=app.StopTimeEditField.Value;
            assignin('base','StopTime',StopTime);
             a=0:0.1:StopTime;
    userFunc = str2func(['@(a)', funcstr]);
    T_values= userFunc(a);   % t vektörüne uygulama
assignin('base', 'T_input', T_values);

            %Parametreler
            sabitler =[w_pump];
            assignin('base','sabitler',sabitler);
           

            %Başlangıç Koşulları
             assignin('base','x1',app.x1radSlider.Value);
             assignin('base','x2',app.x2radSlider.Value);
             assignin('base','x3',app.x3pEditField.Value);
             assignin('base','x4',app.x4msEditField.Value);

             
             %Kontrol Girişleri
             assignin('base','u_P',app.PompaKontrolGiriivitesu1Slider.Value);
           assignin('base','u_M',app.MotorKontrolGiriipedalu2Slider.Value);
            
             
             StepSize=app.StepSizeEditField.Value;
             assignin('base','StepSize',StepSize);
                         % MP4 dosyasını oku
videoFile = 'C:\Users\ezmec\OneDrive\Desktop\ubp\transmisyon.mp4'; % MP4 dosya yolu buraya yapıştırılacaktır!!!
videoObj = VideoReader(videoFile);

% Video çerçevelerini göster
while hasFrame(videoObj)
    frame = readFrame(videoObj);
   imshow(frame, 'Parent', app.UIAxes); % Çerçeveyi eksene yerleştir
    pause(1/videoObj.FrameRate); % Çerçeve hızıyla duraklat
end

        end

        % Callback function
        function StopTimeSliderValueChanged(app, event)
            value = app.StopTimeSlider.Value;
            app.StopTimeEditField.Value= value;
        end

        % Callback function
        function StopTimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.StopTimeEditField.Value=changingValue;
        end

        % Callback function
        function PompaHzCheckBoxValueChanged(app, event)
            value = app.PompaAsDeiimHzradsCheckBox.Value;
            if value==1
               app.hydropump_speed_diffLine.Visible="on";
            else 
                 app.hydropump_speed_diffLine.Visible="off";
            end

        end

        % Callback function
        function MotorHzCheckBoxValueChanged(app, event)
            value = app.MotorAsDeiimHzradsCheckBox.Value;
             if value==1
               app.hydromotor_speed_diffLine.Visible="on";
            else 
                app.hydromotor_speed_diffLine.Visible="off";
            end

        end

        % Callback function
        function debiCheckBoxValueChanged(app, event)
            value = app.BasnFarkDeiimHzpsCheckBox.Value;
             if value==1
               app.pressure_difference_changeLine.Visible="on";
            else 
                app.pressure_difference_changeLine.Visible="off";
            end

        end

        % Callback function
        function MotorvmesiCheckBoxValueChanged(app, event)
            value = app.AsalvmeDeiimims2CheckBox.Value;
             if value==1
               app.hydromotor_acc_diffLine.Visible="on";
            else 
                app.hydromotor_acc_diffLine.Visible="off";
            end

        end

        % Button down function: HzDeiimleriTab
        function HzDeiimleriTabButtonDown(app, event)

        end

        % Callback function: ADeiimiTab, HzDeiimleriTab
        function ADeiimiTabButtonDown(app, event)

        end

        % Value changed function: PompaAsradCheckBox
        function PompaAsradCheckBoxValueChanged(app, event)
            value = app.PompaAsradCheckBox.Value;
            if value==1
               app.hydropump_angleLine.Visible="on";
            else 
                app.hydropump_angleLine.Visible="off";
            end
        end

        % Value changed function: MotorAsradCheckBox
        function MotorAsradCheckBoxValueChanged(app, event)
            value = app.MotorAsradCheckBox.Value;
             if value==1
               app.hydromotor_angleLine.Visible="on";
            else 
                app.hydromotor_angleLine.Visible="off";
            end

        end

        % Value changed function: BasnFarkpCheckBox
        function BasnFarkpCheckBoxValueChanged(app, event)
            value = app.BasnFarkpCheckBox.Value;
             if value==1
                app. pressure_differenceLine.Visible="on";
            else 
                app. pressure_differenceLine.Visible="off";
            end
        end

        % Value changed function: MotorAsalHzGiriimsCheckBox
        function MotorAsalHzGiriimsCheckBoxValueChanged(app, event)
            value = app.MotorAsalHzGiriimsCheckBox.Value;
             if value==1
                app.hydromotor_accLine.Visible="on";
            else 
                app.hydromotor_accLine.Visible="off";
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.ADeiimiTab
     legend(app.grafik,["hydropump_angle", "hydromotor_angle","pressure_difference", "hydromotor_acceleration_Input"]);
        
            elseif selectedTab == app.HzDeiimleriTab
                legend(app.grafik,["hydropump_speed_diff", "hydromotor_speed_diff","pressure_difference_change", "hydromotor_acceleration_diff"]);
            end
        end

        % Value changed function: StopTimeSlider
        function StopTimeSliderValueChanged2(app, event)
            value = app.StopTimeSlider.Value;
            app.StopTimeEditField.Value=value;
        end

        % Value changing function: StopTimeSlider
        function StopTimeSliderValueChanging2(app, event)
            changingValue = event.Value;
             app.StopTimeEditField.Value=changingValue;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: PompaKontrolGiriivitesu1Slider
        function PompaKontrolGiriivitesu1SliderValueChanged(app, event)
         value=app.PompaKontrolGiriivitesu1Slider.Value;
            app.PompaKontrolGiriivitesu1Slider.Value=value;
        end

        % Callback function
        function TabButtonDown(app, event)

        end

        % Value changed function: PompaAsDeiimHzradsCheckBox
        function PompaAsDeiimHzradsCheckBoxValueChanged(app, event)
            value = app.PompaAsDeiimHzradsCheckBox.Value;
            if value==1
                 app.hydropump_speed_diffLine.Visible = "on";
            else 
                app.hydropump_speed_diffLine.Visible = "off";
            end

        end

        % Value changed function: MotorAsDeiimHzradsCheckBox
        function MotorAsDeiimHzradsCheckBoxValueChanged(app, event)
            value = app.MotorAsDeiimHzradsCheckBox.Value;
             if value==1
                   app.hydromotor_speed_diffLine.Visible = "on";
             else

                   app.hydromotor_speed_diffLine.Visible = "off";
             end
        end

        % Value changed function: BasnFarkDeiimHzpsCheckBox
        function BasnFarkDeiimHzpsCheckBoxValueChanged(app, event)
            value = app.BasnFarkDeiimHzpsCheckBox.Value;
             if value==1
                     app.pressure_difference_changeLine.Visible = "on";
             else
                     app.pressure_difference_changeLine.Visible = "off";
             end

        end

        % Value changed function: AsalvmeDeiimims2CheckBox
        function AsalvmeDeiimims2CheckBoxValueChanged(app, event)
            value = app.AsalvmeDeiimims2CheckBox.Value;
             if value==1
                 app.hydromotor_acc_diffLine.Visible = "on";
             else
                 app.hydromotor_acc_diffLine.Visible = "off";
             end
                 
        end

        % Value changed function: MotorKontrolGiriipedalu2Slider
        function MotorKontrolGiriipedalu2SliderValueChanged(app, event)
            value = app.MotorKontrolGiriipedalu2Slider.Value;
            app.MotorKontrolGiriipedalu2Slider.Value=value;
        end

        % Callback function
        function T_loadNmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.T_loadNmSlider.Value=changingValue;
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            value = app.StopTimeEditField.Value;
    if isnumeric(value) && value >= app.StopTimeSlider.Limits(1) && value <= app.StopTimeSlider.Limits(2)
        app.StopTimeSlider.Value = value;  
    else
        uialert(app.UIFigure, 'Geçersiz değer. Lütfen slider aralığında bir sayı girin.', 'Hata');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafik = uiaxes(app.UIFigure);
            title(app.grafik, 'ÇIKIŞLAR')
            xlabel(app.grafik, 'X')
            ylabel(app.grafik, 'Y')
            zlabel(app.grafik, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hidrostatik Motor Çalışma')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.HidrolikTransmisyonHesaplamaLabel = uilabel(app.UIFigure);
            app.HidrolikTransmisyonHesaplamaLabel.Text = 'Hidrolik Transmisyon Hesaplama';
            app.ParametrelerPanel = uipanel(app.UIFigure);
            app.ParametrelerPanel.Title = 'Parametreler';
            app.SaveParametersButton = uibutton(app.ParametrelerPanel, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.GiriPompaAsalHzradsnEditFieldLabel = uilabel(app.ParametrelerPanel);
            app.GiriPompaAsalHzradsnEditFieldLabel.Text = 'Giriş Pompa Açısal Hızı rad/sn';
            app.GiriPompaAsalHzradsnEditField = uieditfield(app.ParametrelerPanel, 'numeric');
            app.GiriPompaAsalHzradsnEditField.Value = 157;
            app.T_loadNmEditFieldLabel = uilabel(app.ParametrelerPanel);
            app.T_loadNmEditFieldLabel.Text = 'T_load(Nm)';
            app.T_loadNmEditField = uieditfield(app.ParametrelerPanel, 'text');
            app.T_loadNmEditField.Value = '100*sin(a)';
            app.KontrolGirileriECUPanel = uipanel(app.UIFigure);
            app.KontrolGirileriECUPanel.Title = 'Kontrol Girişleri(ECU)';
            app.PompaKontrolGiriivitesu1SliderLabel = uilabel(app.KontrolGirileriECUPanel);
            app.PompaKontrolGiriivitesu1SliderLabel.Text = 'Pompa Kontrol Girişi(vites) /u1';
            app.PompaKontrolGiriivitesu1Slider = uislider(app.KontrolGirileriECUPanel);
            app.PompaKontrolGiriivitesu1Slider.Limits = [-1 1];
            app.PompaKontrolGiriivitesu1Slider.ValueChangedFcn = createCallbackFcn(app, @PompaKontrolGiriivitesu1SliderValueChanged, true);
            app.MotorKontrolGiriipedalu2SliderLabel = uilabel(app.KontrolGirileriECUPanel);
            app.MotorKontrolGiriipedalu2SliderLabel.Text = 'Motor Kontrol Girişi/(pedal)u2';
            app.MotorKontrolGiriipedalu2Slider = uislider(app.KontrolGirileriECUPanel);
            app.MotorKontrolGiriipedalu2Slider.Limits = [0 1];
            app.MotorKontrolGiriipedalu2Slider.ValueChangedFcn = createCallbackFcn(app, @MotorKontrolGiriipedalu2SliderValueChanged, true);
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.StopTimeEditFieldLabel = uilabel(app.SimulationPanel);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Value = 10;
            app.RunSimulationButton = uibutton(app.SimulationPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.StepSizeEditFieldLabel = uilabel(app.SimulationPanel);
            app.StepSizeEditFieldLabel.Text = 'StepSize';
            app.StepSizeEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.StepSizeEditField.Value = 0.0001;
            app.StopTimeSliderLabel = uilabel(app.SimulationPanel);
            app.StopTimeSliderLabel.Text = 'Stop Time';
            app.StopTimeSlider = uislider(app.SimulationPanel);
            app.StopTimeSlider.ValueChangedFcn = createCallbackFcn(app, @StopTimeSliderValueChanged2, true);
            app.StopTimeSlider.ValueChangingFcn = createCallbackFcn(app, @StopTimeSliderValueChanging2, true);
            app.VeriYokLampLabel = uilabel(app.SimulationPanel);
            app.VeriYokLampLabel.Text = 'Veri  Yok';
            app.VeriYokLamp = uilamp(app.SimulationPanel);
            app.SistemGirileriPanel = uipanel(app.UIFigure);
            app.SistemGirileriPanel.Title = 'Sistem Girişleri';
            app.x3pEditFieldLabel = uilabel(app.SistemGirileriPanel);
            app.x3pEditFieldLabel.Text = 'x3(/\p)';
            app.x3pEditField = uieditfield(app.SistemGirileriPanel, 'numeric');
            app.x3pEditField.Value = 100000;
            app.x4msEditFieldLabel = uilabel(app.SistemGirileriPanel);
            app.x4msEditFieldLabel.Text = 'x4(m/s)';
            app.x4msEditField = uieditfield(app.SistemGirileriPanel, 'numeric');
            app.x1radSliderLabel = uilabel(app.SistemGirileriPanel);
            app.x1radSliderLabel.Text = 'x1(rad)';
            app.x1radSlider = uislider(app.SistemGirileriPanel);
            app.x1radSlider.Limits = [-1 1];
            app.x1radSlider.MajorTicks = [-1 0 1];
            app.x2radSliderLabel = uilabel(app.SistemGirileriPanel);
            app.x2radSliderLabel.Text = 'x2(rad)';
            app.x2radSlider = uislider(app.SistemGirileriPanel);
            app.x2radSlider.Limits = [0 1];
            app.x2radSlider.MajorTicks = [0 0.5 1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HzDeiimleriTab = uitab(app.TabGroup);
            app.HzDeiimleriTab.SizeChangedFcn = createCallbackFcn(app, @ADeiimiTabButtonDown, true);
            app.HzDeiimleriTab.Title = 'Hız Değişimleri';
            app.HzDeiimleriTab.ButtonDownFcn = createCallbackFcn(app, @HzDeiimleriTabButtonDown, true);
            app.PompaAsDeiimHzradsCheckBox = uicheckbox(app.HzDeiimleriTab);
            app.PompaAsDeiimHzradsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PompaAsDeiimHzradsCheckBoxValueChanged, true);
            app.PompaAsDeiimHzradsCheckBox.Text = 'Pompa Açısı Değişim Hızı(rad/s)';
            app.MotorAsDeiimHzradsCheckBox = uicheckbox(app.HzDeiimleriTab);
            app.MotorAsDeiimHzradsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorAsDeiimHzradsCheckBoxValueChanged, true);
            app.MotorAsDeiimHzradsCheckBox.Text = 'Motor Açısı Değişim Hızı(rad/s)';
            app.BasnFarkDeiimHzpsCheckBox = uicheckbox(app.HzDeiimleriTab);
            app.BasnFarkDeiimHzpsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BasnFarkDeiimHzpsCheckBoxValueChanged, true);
            app.BasnFarkDeiimHzpsCheckBox.Text = 'Basınç Farkı Değişim Hızı(/\p/s]';
            app.AsalvmeDeiimims2CheckBox = uicheckbox(app.HzDeiimleriTab);
            app.AsalvmeDeiimims2CheckBox.ValueChangedFcn = createCallbackFcn(app, @AsalvmeDeiimims2CheckBoxValueChanged, true);
            app.AsalvmeDeiimims2CheckBox.Text = 'Açısal İvme Değişimi(m/s^2)';
            app.ADeiimiTab = uitab(app.TabGroup);
            app.ADeiimiTab.Title = 'Açı Değişimi';
            app.ADeiimiTab.ButtonDownFcn = createCallbackFcn(app, @ADeiimiTabButtonDown, true);
            app.PompaAsradCheckBox = uicheckbox(app.ADeiimiTab);
            app.PompaAsradCheckBox.ValueChangedFcn = createCallbackFcn(app, @PompaAsradCheckBoxValueChanged, true);
            app.PompaAsradCheckBox.Text = 'Pompa Açısı(rad)';
            app.MotorAsradCheckBox = uicheckbox(app.ADeiimiTab);
            app.MotorAsradCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorAsradCheckBoxValueChanged, true);
            app.MotorAsradCheckBox.Text = 'Motor Açısı(rad)';
            app.BasnFarkpCheckBox = uicheckbox(app.ADeiimiTab);
            app.BasnFarkpCheckBox.ValueChangedFcn = createCallbackFcn(app, @BasnFarkpCheckBoxValueChanged, true);
            app.BasnFarkpCheckBox.Text = 'Basınç Farkı(/\p)';
            app.MotorAsalHzGiriimsCheckBox = uicheckbox(app.ADeiimiTab);
            app.MotorAsalHzGiriimsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorAsalHzGiriimsCheckBoxValueChanged, true);
            app.MotorAsalHzGiriimsCheckBox.Text = 'Motor Açısal Hız Girişi(m/s)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Zaman'; 'Pompa Hızı'; 'Basınç Farkı Değişimi'; 'Açısal İvme'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hidrostatik_transmisyon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end